classdef KinetiCal_alpha < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VoltageCalciumAnalysisLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        JobFileTab                      matlab.ui.container.Tab
        setOutputDirButton              matlab.ui.control.Button
        OutputDirectoryEditField        matlab.ui.control.EditField
        OutputDirectoryEditFieldLabel   matlab.ui.control.Label
        ChangeAliasesButton             matlab.ui.control.Button
        SelectionFilterButton           matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        LoadJobFileButton               matlab.ui.control.Button
        NewJobFileButton                matlab.ui.control.Button
        ConfigureTab                    matlab.ui.container.Tab
        ExperimentalParametersPanel     matlab.ui.container.Panel
        AcquisitionRate                 matlab.ui.control.EditField
        AcquisitionRateframespersecondLabel  matlab.ui.control.Label
        SingleCellSegmentationCellposeButtonGroup  matlab.ui.container.ButtonGroup
        MaskFolderDropDown              matlab.ui.control.DropDown
        UseCellpose                     matlab.ui.control.CheckBox
        MandatoryCellposeInputsPanel    matlab.ui.container.Panel
        OptionalCellposeArgumentsEditField  matlab.ui.control.EditField
        OptionalCellposeArgumentsLabel  matlab.ui.control.Label
        MaskFromFolder                  matlab.ui.control.RadioButton
        MaskFromVideo                   matlab.ui.control.RadioButton
        BackButton_2                    matlab.ui.control.Button
        NextButton_3                    matlab.ui.control.Button
        PerformanceParametersPanel      matlab.ui.container.Panel
        MaxRAMGBLabel                   matlab.ui.control.Label
        MaxCPUMem                       matlab.ui.control.EditField
        MaxGPUMemEditField              matlab.ui.control.EditField
        MaxGPUMemGBLabel                matlab.ui.control.Label
        GPUAccelerationDropDown         matlab.ui.control.DropDown
        GPUAccelerationDropDownLabel    matlab.ui.control.Label
        RunAnalysisTab                  matlab.ui.container.Tab
        QuitAnalysisButton              matlab.ui.control.Button
        BackButton_3                    matlab.ui.control.Button
        StartAnalysisButton             matlab.ui.control.Button
        UITableStatus                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        SelectionFilterDialogApp % App to the selection filter dialog
        AliasDialogApp % App to the manual alias dialog
        RootFolder % Where images start to be discovered
        QuitDialog % Dialog showed while stopping execution
    end
    
    properties (Access = public)
        chosenLevelsForAlias % Description
        Config % Struct to save configuration info. Will be saved as JSON
        Logfile % Full file path to log
        QuitSignal % Interrupt execution
    end
    
    methods (Access = private)
        function updateButtonsTab1(app)
            if height(app.UITable.Data)==0
                app.SelectAllButton.Enable = 'off';
                app.ClearAllButton.Enable  = 'off';
                app.NextButton.Enable      = 'off';
                app.ChangeAliasesButton.Enable    = 'off';
                app.SelectionFilterButton.Enable  = 'off';
            else
                app.SelectAllButton.Enable  = 'on';
                app.ClearAllButton.Enable   = 'on';
                app.NextButton.Enable      = 'on';
                app.ChangeAliasesButton.Enable      = 'on';
                app.SelectionFilterButton.Enable    = 'on';
            end
        end
        
        function saveJobFile(app)
            T = app.UITable.Data;
            jobfile = T(T.Analyze==1,:);
            jobfile = removevars(jobfile,{'Analyze'});
            fullfilepath = fullfile(app.OutputDirectoryEditField.Value,'jobfile.csv');
            writetable(jobfile,fullfilepath,'Delimiter',',')
        end
        
        function addPass(app,wdw_sz,wdw_spc)
            p = height(app.UITablePIV.Data);
            newRow = table;
            newRow.Pass =  p+1;
            newRow.WindowSize = wdw_sz;
            newRow.WindowSpacing = wdw_spc;
            app.UITablePIV.Data = [app.UITablePIV.Data;newRow];
        end
        
        function updateConfig(app)
            % Other Parameters
            app.Config.OtherParameters.FrameRate = str2double(app.AcquisitionRate.Value);
            
            % Performance Parameters
            app.Config.Performance.MaxRAM = str2double(app.MaxCPUMem.Value);
            if strcmp(app.GPUAccelerationDropDown.Value,'enabled')
                app.Config.Performance.UseGPU = true;
                app.Config.Performance.MaxGPUMem = str2double(app.MaxGPUMemEditField.Value);
            else
                app.Config.Performance.UseGPU = false;
            end
            
            % Single Cell Parameters
            app.Config.SingleCell.UseCellpose = app.UseCellpose.Value;
            app.Config.SingleCell.Directory   = getCellposeDir(app);
            
            % Output to JSON file
            str = jsonencode(app.Config);
            fid = fopen(fullfile(app.OutputDirectoryEditField.Value,'config.json'),'w');
            fprintf(fid,'%s',str);
            fclose(fid);
            
        end
        
        
        function CPdir = getCellposeDir(app)
            selectedButton = app.SingleCellSegmentationCellposeButtonGroup.SelectedObject;
            selection = selectedButton.Text;
            switch selection
                case {"Use an image from the video to obtain cell masks","1"}
                    CPdir = '';
                case {"Images for cell masks are in a separate folder","2"}
                    CPdir = ['..',filesep,app.MaskFolderDropDown.Value];
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.QuitSignal = 0;
            app.Config = loadDefaultConfig();

            % Startup of First Tab

            % Startup of Second Tab
            if app.Config.Performance.UseGPU
                gpuInfo = gpuDevice;
                gpuText = [{[gpuInfo.Name]},{['AvailableMemory: ', num2str(gpuInfo.AvailableMemory/2^30), ' GB']}];
                app.GPUAccelerationDropDown.Value = 'enabled';
                app.MaxGPUMemEditField.Enable = 'on';
                app.MaxGPUMemEditField.Value = num2str(app.Config.Performance.MaxGPUMem);
            else
                gpuText = [{'No GPU detected. Analysis will run';};{'using multi-threaded CPU version.'}];
                app.GPUAccelerationDropDown.Value = 'disabled';
                app.GPUAccelerationDropDown.Enable = 'off';
                app.MaxGPUMemEditField.Enable = 'off';
            end
            app.GPUAccelerationDropDown.Tooltip = gpuText;
            app.MaxCPUMem.Value = num2str(app.Config.Performance.MaxRAM);
            
        end

        % Button pushed function: NewJobFileButton
        function NewJobFileButtonPushed(app, event)
            app.RootFolder = uigetdir('/');
            % These two lines are to return figure to focus after uigetdir
            drawnow;
            figure(app.UIFigure)
            if ~app.RootFolder
                return
            end
            folder_images = discoverImages2(app.RootFolder,app.UIFigure);
            if ~isempty(folder_images)
                A = cell2table(folder_images,'VariableNames',{'Location'});
                [A.Alias,app.chosenLevelsForAlias] = autoAlias(A.Location);
                A.Analyze = true([height(A),1]);
                % Add data to the Table UI Component
                app.UITable.Data = A;
            else
                uialert(app.UIFigure,'No files to analyze found.','Invalid Directory');
                app.UITable.Data(:,:)=[];
            end
            updateButtonsTab1(app);
            OutputDir = app.RootFolder;
            formatOut = 'yyyymmdd_HHMMSS';
            d = datestr(now,formatOut);
            OutputDir = fullfile(OutputDir,['kinetical_run_',d]);
            app.OutputDirectoryEditField.Value = OutputDir;

        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.UITable.Data.Analyze = true(height(app.UITable.Data),1);
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.UITable.Data.Analyze = false(height(app.UITable.Data),1);
        end

        % Button pushed function: SelectionFilterButton
        function SelectionFilterButtonPushed(app, event)
            % Open the options dialog and pass inputs
            app.SelectionFilterDialogApp = SelectionFilterDialog(app);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            mkdir(app.OutputDirectoryEditField.Value)
            try
                jobtable = app.UITable.Data;
                jobtable = jobtable(jobtable.Analyze==1,:);
                % Check that Aliases are unique
                assert(length(unique(jobtable.Alias)) == height(jobtable),...
                    'One or more aliases are repeated. Please generate unique aliases for each entry.');
                % Check that Output Directory is a valid folder
                assert(isfolder(app.OutputDirectoryEditField.Value),'Invalid Output Directory')
            catch ME
                uialert(app.UIFigure,ME.message,'Error');
                return
            end
            
            % Save jobfile
            saveJobFile(app)
            
            % Close potentially leftover open windows
            delete(app.SelectionFilterDialogApp)
            delete(app.AliasDialogApp)
            
            % Populate the dropdown options of single cell
            jobtable = app.UITable.Data;
            jobtable = jobtable(jobtable.Analyze==1,:);
            adir = jobtable.Location{1};
            s = regexp(adir, filesep, 'split');
            folderUp = fullfile(s{1:length(s)-1});
            contents = dir(folderUp);
            folders  = contents([contents.isdir]);
            toRemove = strcmp({folders.name},".") |  strcmp({folders.name},"..");
            choices = folders(~toRemove);
            app.MaskFolderDropDown.Items = {choices.name};

            % Move to next Tab
            app.TabGroup.SelectedTab = app.ConfigureTab;
        end

        % Button pushed function: LoadJobFileButton
        function LoadJobFileButtonPushed(app, event)
            [f, p] = uigetfile({'*.csv'},'Pick a jobfile');
            drawnow;
            figure(app.UIFigure)
            if ~f
                return
            end
            A = readJobFile2(f, p);
            A.Analyze = true([height(A),1]);
            app.UITable.Data = A;
            
            updateButtonsTab1(app);
            
            app.OutputDirectoryEditField.Value = p;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            
        end

        % Button pushed function: ChangeAliasesButton
        function ChangeAliasesButtonPushed(app, event)
            app.AliasDialogApp = MakeAliasDialogApp(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Disable tab navigation with the tabgroup
            app.TabGroup.SelectedTab = event.OldValue;
            uialert(app.UIFigure,'Use the Next and Back buttons to navigate the GUI.','Action Not Permitted');
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.JobFileTab;
        end

        % Callback function
        function ReferenceSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ReferenceSelectionButtonGroup.SelectedObject;
            updateRefSelFieldEnable(app,selectedButton.Text)
        end

        % Button pushed function: setOutputDirButton
        function setOutputDirButtonPushed(app, event)
            OutputDir = uigetdir(app.RootFolder);
            drawnow;
            figure(app.UIFigure)
            if ~OutputDir
                return
            end
            formatOut = 'yyyymmdd_HHMMSS';
            d = datestr(now,formatOut);
            OutputDir = fullfile(OutputDir,['contractility_run_',d]);
            app.OutputDirectoryEditField.Value = OutputDir;

        end

        % Value changed function: GPUAccelerationDropDown
        function GPUAccelerationDropDownValueChanged(app, event)
            value = app.GPUAccelerationDropDown.Value;
            if strcmp(value,'enabled')
                app.MaxGPUMemEditField.Enable = 'on';
                gDev = gpuDevice;
                app.MaxGPUMemEditField.Value = num2str(round(gDev.AvailableMemory/1e9,1));
            else
                app.MaxGPUMemEditField.Enable = 'off';
            end
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            try
                % Check 'Other Parameters'
                assert(str2double(app.AcquisitionRate.Value)>0,'Acquisition rate must be a positive number.');

                % Check performance parameters
                assert(isnumeric(str2double(app.MaxCPUMem.Value)),'Max RAM must be a number.');
                if strcmp(app.GPUAccelerationDropDown.Value,'enabled')
                    assert(isnumeric(str2double(app.MaxGPUMemEditField.Value)),'Max RAM must be a number.');
                end
            catch ME
                uialert(app.UIFigure,ME.message,'Error');
                return
            end
            % Update configuration
            updateConfig(app)
            
            % Move to next Tab
            app.TabGroup.SelectedTab = app.RunAnalysisTab;
            
            % Update status table
            jobtable = app.UITable.Data;
            jobtable = jobtable(jobtable.Analyze==1,:);
            statustable = removevars(jobtable,{'Analyze'});
            statustable.Status(:) = {'Not Started'};
            app.UITableStatus.Data = statustable;
            drawnow
        end

        % Button pushed function: StartAnalysisButton
        function StartAnalysisButtonPushed(app, event)
            app.StartAnalysisButton.Enable = 'off';
            app.BackButton_3.Enable = 'off';
            app.QuitAnalysisButton.Enable = 'on';
            % Create Time Log
            logfile = fullfile(app.OutputDirectoryEditField.Value,['contractility_log_',datestr(now,30),'.log']);
            app.Logfile = logfile;
            fid = fopen(logfile,'w');
            fclose(fid);
            
            % Launch main function
            calcium_analysis_1_0(app);
            
            % If stopped signal was pushed, update buttons
            if app.QuitSignal
                app.StartAnalysisButton.Enable = 'on';
                app.BackButton_3.Enable = 'on';
                app.QuitSignal = 0;
                delete(app.QuitDialog)
            end
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            % Move to previous Tab
            app.TabGroup.SelectedTab = app.ConfigureTab;
        end

        % Button pushed function: QuitAnalysisButton
        function QuitAnalysisButtonPushed(app, event)
            app.QuitDialog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Stopping execution, please wait.',...
                'Indeterminate','on');
            drawnow
            figure(app.UIFigure)
            app.QuitAnalysisButton.Enable = 'off';
            app.QuitSignal = 1;
        end

        % Value changed function: UseCellpose
        function UseCellposeValueChanged(app, event)
            value = app.UseCellpose.Value;
            if value
                app.MaskFromVideo.Enable = 'on';
                app.MaskFromFolder.Enable = 'on';
                app.MaskFolderDropDown.Enable='on';
                app.OptionalCellposeArgumentsEditField.Enable = 'on';

            else
                app.MaskFromVideo.Enable = 'off';
                app.MaskFromFolder.Enable = 'off';
                app.MaskFolderDropDown.Enable='off';
                app.OptionalCellposeArgumentsEditField.Enable = 'off';

            end
        end

        % Selection changed function: 
        % SingleCellSegmentationCellposeButtonGroup
        function SingleCellSegmentationCellposeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SingleCellSegmentationCellposeButtonGroup.SelectedObject;
            selection = selectedButton.Text;
            switch selection
                case {"Use an image from the video to obtain cell masks","1"}
                    app.MaskFolderDropDown.Enable = 'off';
                case {"Images for cell masks are in a separate folder","2"}
                    app.MaskFolderDropDown.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Contractility Analysis';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.JobFileTab = uitab(app.TabGroup);
            app.JobFileTab.Title = '1. Job File';
            app.NewJobFileButton = uibutton(app.JobFileTab, 'push');
            app.NewJobFileButton.ButtonPushedFcn = createCallbackFcn(app, @NewJobFileButtonPushed, true);
            app.NewJobFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'file-solid.svg');
            app.NewJobFileButton.Text = 'New Job File';
            app.LoadJobFileButton = uibutton(app.JobFileTab, 'push');
            app.LoadJobFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadJobFileButtonPushed, true);
            app.LoadJobFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder-solid.svg');
            app.LoadJobFileButton.Text = 'Load Job File';
            app.UITable = uitable(app.JobFileTab);
            app.UITable.ColumnName = {'Location'; 'Alias'; 'Analyze'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = [false false true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.SelectAllButton = uibutton(app.JobFileTab, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Icon = fullfile(pathToMLAPP, 'icons', 'square-check-solid.svg');
            app.SelectAllButton.Enable = 'off';
            app.SelectAllButton.Text = 'Select All';
            app.ClearAllButton = uibutton(app.JobFileTab, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Icon = fullfile(pathToMLAPP, 'icons', 'square-xmark-solid.svg');
            app.ClearAllButton.Enable = 'off';
            app.ClearAllButton.Text = 'Clear All';
            app.NextButton = uibutton(app.JobFileTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.SelectionFilterButton = uibutton(app.JobFileTab, 'push');
            app.SelectionFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectionFilterButtonPushed, true);
            app.SelectionFilterButton.Icon = fullfile(pathToMLAPP, 'icons', 'filter-solid.svg');
            app.SelectionFilterButton.Enable = 'off';
            app.SelectionFilterButton.Text = 'Selection Filter';
            app.ChangeAliasesButton = uibutton(app.JobFileTab, 'push');
            app.ChangeAliasesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeAliasesButtonPushed, true);
            app.ChangeAliasesButton.Icon = fullfile(pathToMLAPP, 'icons', 'pen-to-square-solid.svg');
            app.ChangeAliasesButton.Enable = 'off';
            app.ChangeAliasesButton.Text = 'Change Aliases';
            app.OutputDirectoryEditFieldLabel = uilabel(app.JobFileTab);
            app.OutputDirectoryEditFieldLabel.Text = ' Output Directory:';
            app.OutputDirectoryEditField = uieditfield(app.JobFileTab, 'text');
            app.setOutputDirButton = uibutton(app.JobFileTab, 'push');
            app.setOutputDirButton.ButtonPushedFcn = createCallbackFcn(app, @setOutputDirButtonPushed, true);
            app.setOutputDirButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder-tree-solid.svg');
            app.setOutputDirButton.Text = '';
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = '2. Configure';
            app.PerformanceParametersPanel = uipanel(app.ConfigureTab);
            app.PerformanceParametersPanel.Title = 'Performance Parameters';
            app.GPUAccelerationDropDownLabel = uilabel(app.PerformanceParametersPanel);
            app.GPUAccelerationDropDownLabel.Text = 'GPU Acceleration';
            app.GPUAccelerationDropDown = uidropdown(app.PerformanceParametersPanel);
            app.GPUAccelerationDropDown.Items = {'enabled', 'disabled'};
            app.GPUAccelerationDropDown.ValueChangedFcn = createCallbackFcn(app, @GPUAccelerationDropDownValueChanged, true);
            app.GPUAccelerationDropDown.Value = 'disabled';
            app.MaxGPUMemGBLabel = uilabel(app.PerformanceParametersPanel);
            app.MaxGPUMemGBLabel.Text = 'Max GPU Mem. (GB) ';
            app.MaxGPUMemEditField = uieditfield(app.PerformanceParametersPanel, 'text');
            app.MaxGPUMemEditField.Enable = 'off';
            app.MaxGPUMemEditField.Value = '1';
            app.MaxCPUMem = uieditfield(app.PerformanceParametersPanel, 'text');
            app.MaxCPUMem.Value = '5';
            app.MaxRAMGBLabel = uilabel(app.PerformanceParametersPanel);
            app.MaxRAMGBLabel.Text = 'Max RAM (GB)';
            app.NextButton_3 = uibutton(app.ConfigureTab, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.BackButton_2 = uibutton(app.ConfigureTab, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.SingleCellSegmentationCellposeButtonGroup = uibuttongroup(app.ConfigureTab);
            app.SingleCellSegmentationCellposeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SingleCellSegmentationCellposeButtonGroupSelectionChanged, true);
            app.SingleCellSegmentationCellposeButtonGroup.Title = 'Single Cell Segmentation (Cellpose)';
            app.MaskFromVideo = uiradiobutton(app.SingleCellSegmentationCellposeButtonGroup);
            app.MaskFromVideo.Text = 'Use an image from the video to obtain cell masks';
            app.MaskFromVideo.Value = true;
            app.MaskFromFolder = uiradiobutton(app.SingleCellSegmentationCellposeButtonGroup);
            app.MaskFromFolder.Text = 'Images for cell masks are in a separate folder';
            app.OptionalCellposeArgumentsLabel = uilabel(app.SingleCellSegmentationCellposeButtonGroup);
            app.OptionalCellposeArgumentsLabel.Text = '(Optional) Cellpose Arguments:';
            app.OptionalCellposeArgumentsEditField = uieditfield(app.SingleCellSegmentationCellposeButtonGroup, 'text');
            app.MandatoryCellposeInputsPanel = uipanel(app.SingleCellSegmentationCellposeButtonGroup);
            app.MandatoryCellposeInputsPanel.Tooltip = {'--dir and --'};
            app.MandatoryCellposeInputsPanel.Title = 'Mandatory Cellpose Inputs:';
            app.UseCellpose = uicheckbox(app.SingleCellSegmentationCellposeButtonGroup);
            app.UseCellpose.ValueChangedFcn = createCallbackFcn(app, @UseCellposeValueChanged, true);
            app.UseCellpose.Text = 'Enable Single Cell Analysis';
            app.UseCellpose.Value = true;
            app.MaskFolderDropDown = uidropdown(app.SingleCellSegmentationCellposeButtonGroup);
            app.MaskFolderDropDown.Enable = 'off';
            app.ExperimentalParametersPanel = uipanel(app.ConfigureTab);
            app.ExperimentalParametersPanel.Title = 'Experimental Parameters';
            app.AcquisitionRateframespersecondLabel = uilabel(app.ExperimentalParametersPanel);
            app.AcquisitionRateframespersecondLabel.Text = 'Acquisition Rate (frames per second)';
            app.AcquisitionRate = uieditfield(app.ExperimentalParametersPanel, 'text');
            app.RunAnalysisTab = uitab(app.TabGroup);
            app.RunAnalysisTab.Title = '3. Run Analysis';
            app.UITableStatus = uitable(app.RunAnalysisTab);
            app.UITableStatus.ColumnName = {'Location'; 'Alias'; 'Status'};
            app.UITableStatus.RowName = {};
            app.UITableStatus.ColumnEditable = [false false false];
            app.StartAnalysisButton = uibutton(app.RunAnalysisTab, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysisButtonPushed, true);
            app.StartAnalysisButton.Icon = fullfile(pathToMLAPP, 'icons', 'play-solid.svg');
            app.StartAnalysisButton.Text = 'Start Analysis';
            app.BackButton_3 = uibutton(app.RunAnalysisTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.QuitAnalysisButton = uibutton(app.RunAnalysisTab, 'push');
            app.QuitAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @QuitAnalysisButtonPushed, true);
            app.QuitAnalysisButton.Icon = fullfile(pathToMLAPP, 'icons', 'circle-xmark-solid.svg');
            app.QuitAnalysisButton.Enable = 'off';
            app.QuitAnalysisButton.Text = 'Quit Analysis';
            app.VoltageCalciumAnalysisLabel = uilabel(app.UIFigure);
            app.VoltageCalciumAnalysisLabel.Text = 'Voltage/Calcium Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KinetiCal_alpha

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end